classdef WAC_matlabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        ConfigurationTab             matlab.ui.container.Tab
        GetManuallyButton            matlab.ui.control.Button
        Node2IDEditField             matlab.ui.control.EditField
        Node2IDEditFieldLabel        matlab.ui.control.Label
        Node3IDEditField             matlab.ui.control.EditField
        Node3IDEditFieldLabel        matlab.ui.control.Label
        Node1IDEditField             matlab.ui.control.EditField
        Node1IDEditFieldLabel        matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        SuccessfulLamp               matlab.ui.control.Lamp
        UsesavedpasswordCheckBox     matlab.ui.control.CheckBox
        PortEditField                matlab.ui.control.NumericEditField
        PortEditFieldLabel           matlab.ui.control.Label
        ConnectButton                matlab.ui.control.Button
        PasswordEditField            matlab.ui.control.EditField
        PasswordEditFieldLabel       matlab.ui.control.Label
        ClientIDEditField            matlab.ui.control.EditField
        ClientIDEditFieldLabel       matlab.ui.control.Label
        BrokeraddressEditField       matlab.ui.control.EditField
        BrokeraddressEditFieldLabel  matlab.ui.control.Label
        NodesTab                     matlab.ui.container.Tab
        Node3Panel                   matlab.ui.container.Panel
        n3_pm10Label                 matlab.ui.control.Label
        n3_pm25Label                 matlab.ui.control.Label
        n3_co2Label                  matlab.ui.control.Label
        n3_humLabel                  matlab.ui.control.Label
        n3_tempLabel                 matlab.ui.control.Label
        ParticulesPM10Label_3        matlab.ui.control.Label
        ParticulesPM25Label_3        matlab.ui.control.Label
        CO2Label_3                   matlab.ui.control.Label
        HumiditLabel_3               matlab.ui.control.Label
        TempratureLabel_3            matlab.ui.control.Label
        Node2Panel                   matlab.ui.container.Panel
        n2_pm10Label                 matlab.ui.control.Label
        n2_pm25Label                 matlab.ui.control.Label
        n2_co2Label                  matlab.ui.control.Label
        n2_humLabel                  matlab.ui.control.Label
        n2_tempLabel                 matlab.ui.control.Label
        ParticulesPM10Label_2        matlab.ui.control.Label
        ParticulesPM25Label_2        matlab.ui.control.Label
        CO2Label_2                   matlab.ui.control.Label
        HumiditLabel_2               matlab.ui.control.Label
        TempratureLabel_2            matlab.ui.control.Label
        Node1Panel                   matlab.ui.container.Panel
        n1_pm10Label                 matlab.ui.control.Label
        n1_pm25Label                 matlab.ui.control.Label
        n1_co2Label                  matlab.ui.control.Label
        n1_humLabel                  matlab.ui.control.Label
        n1_tempLabel                 matlab.ui.control.Label
        ParticulesPM10Label          matlab.ui.control.Label
        ParticulesPM25Label          matlab.ui.control.Label
        CO2Label                     matlab.ui.control.Label
        HumiditLabel                 matlab.ui.control.Label
        TempratureLabel              matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        PMAxes                       matlab.ui.control.UIAxes
        CO2Axes                      matlab.ui.control.UIAxes
        HumAxes                      matlab.ui.control.UIAxes
        TempAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        myMqtt;
        savedAPIKey = 'NNSXS.OPRZ6VBWDQGH6L2YBZHYI4H3COEXAL64H34NDYI.SSBYGQ7EGRCSNNJ2CKUDRHUSQYCFHGYPH6VX74IXDDYKU44FV4NQ';
        subscription; % Sub to all topics
        isConnected;
        timerMQTT;        % Timer object that allows to check mqtt
        messageJson;

        % Variables that contains data from the 3 nodes
        node1Time = datetime;
        node1Data = struct('co2',[],'humidity',[],'pm10',[],'pm25',[],'temperature',[]);
        node2Time = datetime;
        node2Data = struct('co2',[],'humidity',[],'pm10',[],'pm25',[],'temperature',[]);
        node3Time = datetime;
        node3Data = struct('co2',[],'humidity',[],'pm10',[],'pm25',[],'temperature',[]);

        isFirstTime_node1 = true;
        isFirstTime_node2 = true;
        isFirstTime_node3 = true;
    end
    
    methods (Access = private)
        
        function updateNode(app, time, topic, data)
            for i=1:length(time)
                temp = jsondecode(data(i));
                if(isfield(temp,'uplink_message'))
                    if(contains(topic(i), app.Node1IDEditField.Value))
                        if(app.isFirstTime_node1)
                            app.node1Time(1) = deal(time(i));
                            app.node1Data(1) = temp.uplink_message.decoded_payload;
                            app.isFirstTime_node1 = false;
                        else
                            app.node1Time(end+1) = deal(time(i));
                            app.node1Data(end+1) = temp.uplink_message.decoded_payload;
                        end
                    elseif(contains(topic(i), app.Node2IDEditField.Value))
                        if(app.isFirstTime_node2)
                            app.node2Time(1) = deal(time(i));
                            app.node2Data(1) = temp.uplink_message.decoded_payload;
                            app.isFirstTime_node2 = false;
                        else
                            app.node2Time(end+1) = deal(time(i));
                            app.node2Data(end+1) = temp.uplink_message.decoded_payload;
                        end
                    elseif(contains(topic(i), app.Node3IDEditField.Value))
                        if(app.isFirstTime_node3)
                            app.node3Time(1) = deal(time(i));
                            app.node3Data(1) = temp.uplink_message.decoded_payload;
                            app.isFirstTime_node3 = false;
                        else
                            app.node3Time(end+1) = deal(time(i));
                            app.node3Data(end+1) = temp.uplink_message.decoded_payload;
                        end
                    end
                end   
            end
            if(~app.isFirstTime_node1)
                t   = [app.node1Data.temperature];
                c   = [app.node1Data.co2];
                h   = [app.node1Data.humidity];
                p10 = [app.node1Data.pm10];
                p25 = [app.node1Data.pm25];
                app.n1_tempLabel.Text = string(t(end)) + " °C";
                app.n1_co2Label.Text = string(c(end)) + " ppm";
                app.n1_humLabel.Text = string(h(end)) + " %";
                app.n1_pm10Label.Text = string(p10(end)) + " µg/m^3";
                app.n1_pm25Label.Text = string(p25(end)) + " µg/m^3";
            end
            if(~app.isFirstTime_node2)
                t   = [app.node2Data.temperature];
                c   = [app.node2Data.co2];
                h   = [app.node2Data.humidity];
                p10 = [app.node2Data.pm10];
                p25 = [app.node2Data.pm25];
                app.n2_tempLabel.Text = string(t(end)) + " °C";
                app.n2_co2Label.Text = string(c(end)) + " ppm";
                app.n2_humLabel.Text = string(h(end)) + " %";
                app.n2_pm10Label.Text = string(p10(end)) + " µg/m^3";
                app.n2_pm25Label.Text = string(p25(end)) + " µg/m^3";
            end
            if(~app.isFirstTime_node3)
                t   = [app.node3Data.temperature];
                c   = [app.node3Data.co2];
                h   = [app.node3Data.humidity];
                p10 = [app.node3Data.pm10];
                p25 = [app.node3Data.pm25];
                app.n3_tempLabel.Text = string(t(end)) + " °C";
                app.n3_co2Label.Text = string(c(end)) + " ppm";
                app.n3_humLabel.Text = string(h(end)) + " %";
                app.n3_pm10Label.Text = string(p10(end)) + " µg/m^3";
                app.n3_pm25Label.Text = string(p25(end)) + " µg/m^3";
            end

            updateGraph(app);
        end
        
        
        function updateGraph(app)
            cla(app.TempAxes)
            hold(app.TempAxes,'on')
            if(~app.isFirstTime_node1)
                plot(app.TempAxes, app.node1Time, [app.node1Data.temperature], 'ro', 'DisplayName','Node1');
            end
            if(~app.isFirstTime_node2)
                plot(app.TempAxes, app.node2Time, [app.node2Data.temperature], 'go', 'DisplayName','Node2');
            end
            if(~app.isFirstTime_node3)
                plot(app.TempAxes, app.node3Time, [app.node3Data.temperature], 'bo', 'DisplayName','Node2');
            end
            hold(app.TempAxes,'off')

            cla(app.CO2Axes)
            hold(app.CO2Axes,'on')
            if(~app.isFirstTime_node1)
                plot(app.CO2Axes, app.node1Time, [app.node1Data.co2], 'ro', 'DisplayName','Node1');
            end
            if(~app.isFirstTime_node2)
                plot(app.CO2Axes, app.node2Time, [app.node2Data.co2], 'go', 'DisplayName','Node2');
            end
            if(~app.isFirstTime_node3)
                plot(app.CO2Axes, app.node3Time, [app.node3Data.co2], 'bo', 'DisplayName','Node3');
            end
            hold(app.CO2Axes,'off')

            cla(app.HumAxes)
            hold(app.HumAxes,'on')
            if(~app.isFirstTime_node1)
                plot(app.HumAxes, app.node1Time, [app.node1Data.humidity], 'ro', 'DisplayName','Node1');
            end
            if(~app.isFirstTime_node2)
                plot(app.HumAxes, app.node2Time, [app.node2Data.humidity], 'go', 'DisplayName','Node2');
            end
            if(~app.isFirstTime_node3)
                plot(app.HumAxes, app.node3Time, [app.node3Data.humidity], 'bo', 'DisplayName','Node3');
            end
            hold(app.HumAxes,'off')

            cla(app.PMAxes)
            hold(app.PMAxes,'on')
            if(~app.isFirstTime_node1)
                plot(app.PMAxes, app.node1Time, [app.node1Data.pm10], 'ro', 'DisplayName','Node1');
            end
            if(~app.isFirstTime_node2)
                plot(app.PMAxes, app.node2Time, [app.node2Data.pm10], 'go', 'DisplayName','Node2');
            end
            if(~app.isFirstTime_node3)
                plot(app.PMAxes, app.node3Time, [app.node3Data.pm10], 'bo', 'DisplayName','Node3');
            end
            hold(app.PMAxes,'off')

        end
    end
    
    methods (Access = public)
        
        function timerRefresh(app,~,~)
            disp('timer hit');
            app.messageJson = readall(app.subscription);
            if(~isempty(app.messageJson))
                dataTime = app.messageJson.Time;
                dataTopic = app.messageJson.Topic;
                dataData = app.messageJson.Data;
                updateNode(app, dataTime, dataTopic, dataData);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.isConnected = false;

            %Create timer
            app.timerMQTT = timer(...
                'ExecutionMode', 'fixedSpacing',...
                'Period', 60, ...
                'StartDelay', 150, ...
                'BusyMode', 'queue');
            app.timerMQTT.TimerFcn = @app.timerRefresh;

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if(app.isConnected)
                app.SuccessfulLamp.Color = [0.80,0.80,0.80];
                app.ConnectButton.Text = "Connect";
                unsubscribeAll(app.myMqtt);
                app.isConnected = false;
                stop(app.timerMQTT);
            else
                if(app.UsesavedpasswordCheckBox.Value)
                    pass = app.savedAPIKey;
                else
                    pass = app.PasswordEditField.Value;
                end
                app.myMqtt = mqtt(strcat('tcp://',app.BrokeraddressEditField.Value),'Username',app.ClientIDEditField.Value,'Password',pass,'Port',app.PortEditField.Value);
                app.subscription = subscribe(app.myMqtt, '#');
                app.SuccessfulLamp.Color = [0.00,1.00,0.00];
                app.ConnectButton.Text = "Disconnect";
                app.isConnected = true;
                start(app.timerMQTT);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.timerMQTT);
            delete(app.timerMQTT);
            delete(app)
            clear
        end

        % Button pushed function: GetManuallyButton
        function GetManuallyButtonPushed(app, event)
            app.messageJson = readall(app.subscription);
            if(~isempty(app.messageJson))
                dataTime = app.messageJson.Time;
                dataTopic = app.messageJson.Topic;
                dataData = app.messageJson.Data;
                updateNode(app, dataTime, dataTopic, dataData);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {571, 571};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {294, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.BrokeraddressEditFieldLabel = uilabel(app.ConfigurationTab);
            app.BrokeraddressEditFieldLabel.Text = 'Broker address';
            app.BrokeraddressEditField = uieditfield(app.ConfigurationTab, 'text');
            app.BrokeraddressEditField.Value = 'eu1.cloud.thethings.network';
            app.ClientIDEditFieldLabel = uilabel(app.ConfigurationTab);
            app.ClientIDEditFieldLabel.Text = 'Client ID';
            app.ClientIDEditField = uieditfield(app.ConfigurationTab, 'text');
            app.ClientIDEditField.Value = 'wac32-1@ttn';
            app.PasswordEditFieldLabel = uilabel(app.ConfigurationTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.ConfigurationTab, 'text');
            app.ConnectButton = uibutton(app.ConfigurationTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PortEditFieldLabel = uilabel(app.ConfigurationTab);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.PortEditField.Value = 1883;
            app.UsesavedpasswordCheckBox = uicheckbox(app.ConfigurationTab);
            app.UsesavedpasswordCheckBox.Text = 'Use saved password';
            app.UsesavedpasswordCheckBox.Value = true;
            app.SuccessfulLamp = uilamp(app.ConfigurationTab);
            app.Image = uiimage(app.ConfigurationTab);
            app.Image.ImageSource = 'logo_wac32_black.png';
            app.Node1IDEditFieldLabel = uilabel(app.ConfigurationTab);
            app.Node1IDEditFieldLabel.Text = 'Node1 ID';
            app.Node1IDEditField = uieditfield(app.ConfigurationTab, 'text');
            app.Node1IDEditField.Value = 'eui-70b3d57ed004ca24';
            app.Node3IDEditFieldLabel = uilabel(app.ConfigurationTab);
            app.Node3IDEditFieldLabel.Text = 'Node3 ID';
            app.Node3IDEditField = uieditfield(app.ConfigurationTab, 'text');
            app.Node3IDEditField.Value = 'eui-0080e115000a9436';
            app.Node2IDEditFieldLabel = uilabel(app.ConfigurationTab);
            app.Node2IDEditFieldLabel.Text = 'Node2 ID';
            app.Node2IDEditField = uieditfield(app.ConfigurationTab, 'text');
            app.Node2IDEditField.Value = 'eui-0080e115000a967f';
            app.GetManuallyButton = uibutton(app.ConfigurationTab, 'push');
            app.GetManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @GetManuallyButtonPushed, true);
            app.GetManuallyButton.Text = 'Get Manually';
            app.NodesTab = uitab(app.TabGroup);
            app.NodesTab.Title = 'Nodes';
            app.Node1Panel = uipanel(app.NodesTab);
            app.Node1Panel.Title = 'Node 1';
            app.TempratureLabel = uilabel(app.Node1Panel);
            app.TempratureLabel.Text = 'Température :';
            app.HumiditLabel = uilabel(app.Node1Panel);
            app.HumiditLabel.Text = 'Humidité :';
            app.CO2Label = uilabel(app.Node1Panel);
            app.CO2Label.Text = 'CO2 :';
            app.ParticulesPM25Label = uilabel(app.Node1Panel);
            app.ParticulesPM25Label.Text = 'Particules PM2.5 :';
            app.ParticulesPM10Label = uilabel(app.Node1Panel);
            app.ParticulesPM10Label.Text = 'Particules PM10 :';
            app.n1_tempLabel = uilabel(app.Node1Panel);
            app.n1_tempLabel.Text = 'n1_temp';
            app.n1_humLabel = uilabel(app.Node1Panel);
            app.n1_humLabel.Text = 'n1_hum';
            app.n1_co2Label = uilabel(app.Node1Panel);
            app.n1_co2Label.Text = 'n1_co2';
            app.n1_pm25Label = uilabel(app.Node1Panel);
            app.n1_pm25Label.Text = 'n1_pm25';
            app.n1_pm10Label = uilabel(app.Node1Panel);
            app.n1_pm10Label.Text = 'n1_pm10';
            app.Node2Panel = uipanel(app.NodesTab);
            app.Node2Panel.Title = 'Node 2';
            app.TempratureLabel_2 = uilabel(app.Node2Panel);
            app.TempratureLabel_2.Text = 'Température :';
            app.HumiditLabel_2 = uilabel(app.Node2Panel);
            app.HumiditLabel_2.Text = 'Humidité :';
            app.CO2Label_2 = uilabel(app.Node2Panel);
            app.CO2Label_2.Text = 'CO2 :';
            app.ParticulesPM25Label_2 = uilabel(app.Node2Panel);
            app.ParticulesPM25Label_2.Text = 'Particules PM2.5 :';
            app.ParticulesPM10Label_2 = uilabel(app.Node2Panel);
            app.ParticulesPM10Label_2.Text = 'Particules PM10 :';
            app.n2_tempLabel = uilabel(app.Node2Panel);
            app.n2_tempLabel.Text = 'n2_temp';
            app.n2_humLabel = uilabel(app.Node2Panel);
            app.n2_humLabel.Text = 'n2_hum';
            app.n2_co2Label = uilabel(app.Node2Panel);
            app.n2_co2Label.Text = 'n2_co2';
            app.n2_pm25Label = uilabel(app.Node2Panel);
            app.n2_pm25Label.Text = 'n2_pm25';
            app.n2_pm10Label = uilabel(app.Node2Panel);
            app.n2_pm10Label.Text = 'n2_pm10';
            app.Node3Panel = uipanel(app.NodesTab);
            app.Node3Panel.Title = 'Node 3';
            app.TempratureLabel_3 = uilabel(app.Node3Panel);
            app.TempratureLabel_3.Text = 'Température :';
            app.HumiditLabel_3 = uilabel(app.Node3Panel);
            app.HumiditLabel_3.Text = 'Humidité :';
            app.CO2Label_3 = uilabel(app.Node3Panel);
            app.CO2Label_3.Text = 'CO2 :';
            app.ParticulesPM25Label_3 = uilabel(app.Node3Panel);
            app.ParticulesPM25Label_3.Text = 'Particules PM2.5 :';
            app.ParticulesPM10Label_3 = uilabel(app.Node3Panel);
            app.ParticulesPM10Label_3.Text = 'Particules PM10 :';
            app.n3_tempLabel = uilabel(app.Node3Panel);
            app.n3_tempLabel.Text = 'n3_temp';
            app.n3_humLabel = uilabel(app.Node3Panel);
            app.n3_humLabel.Text = 'n3_hum';
            app.n3_co2Label = uilabel(app.Node3Panel);
            app.n3_co2Label.Text = 'n3_co2';
            app.n3_pm25Label = uilabel(app.Node3Panel);
            app.n3_pm25Label.Text = 'n3_pm25';
            app.n3_pm10Label = uilabel(app.Node3Panel);
            app.n3_pm10Label.Text = 'n3_pm10';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.TempAxes = uiaxes(app.GridLayout2);
            title(app.TempAxes, 'Température')
            xlabel(app.TempAxes, 'Date')
            ylabel(app.TempAxes, 'Température (°C)')
            zlabel(app.TempAxes, 'Z')
            app.HumAxes = uiaxes(app.GridLayout2);
            title(app.HumAxes, 'Humidité')
            xlabel(app.HumAxes, 'Date')
            ylabel(app.HumAxes, 'Humidité (en %)')
            zlabel(app.HumAxes, 'Z')
            app.CO2Axes = uiaxes(app.GridLayout2);
            title(app.CO2Axes, 'CO2')
            xlabel(app.CO2Axes, 'Date')
            ylabel(app.CO2Axes, 'CO2 (en ppm)')
            zlabel(app.CO2Axes, 'Z')
            app.PMAxes = uiaxes(app.GridLayout2);
            title(app.PMAxes, 'Particules PM10')
            xlabel(app.PMAxes, 'Date')
            ylabel(app.PMAxes, 'Particules (en µg/m3)')
            zlabel(app.PMAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WAC_matlabApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end